classdef FINTER < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FINTERLabel                    matlab.ui.control.Label
        IngresarDatosPanel             matlab.ui.container.Panel
        LotedeDatosYEditFieldLabel     matlab.ui.control.Label
        LotedeDatosYEditField          matlab.ui.control.EditField
        LotedeDatosXEditFieldLabel     matlab.ui.control.Label
        LotedeDatosXEditField          matlab.ui.control.EditField
        InterpolarmediantePolinomiodePanel  matlab.ui.container.Panel
        InterpolarButton               matlab.ui.control.Button
        LosresultadossevernenelCommandWindowLabel  matlab.ui.control.Label
        ElegiralternativaButtonGroup   matlab.ui.container.ButtonGroup
        LagrangeButton                 matlab.ui.control.RadioButton
        NewtonGregoryProgresivoButton  matlab.ui.control.RadioButton
        NewtonGregoryRegresivoButton   matlab.ui.control.RadioButton
        EspecializarPolinomioenunValorPanel  matlab.ui.container.Panel
        CalcularButton                 matlab.ui.control.Button
        IngresarValorKEditFieldLabel   matlab.ui.control.Label
        IngresarValorKEditField        matlab.ui.control.EditField
        ElresultadoseverenelCommandWindowLabel  matlab.ui.control.Label
        FinalizarButton                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InterpolarButton
        function InterpolarButtonPushed(app, event)
%Lo primero que tendría que hacer es preguntar por el método seleccionado - Completar

function res = equis(xi)
    res = 2;
    dif = xi(2) - xi(1);
    for k=1:1:length(xi)
        if k ~= length(xi)        
            sub = xi(k+1) - xi(k);
            if sub ~= dif
                res = 0;
            end
        end
    end
    if res == 2
        res = 1;    
    end
    
end


xi=str2num(get(app.LotedeDatosXEditField,'Value'));
yi=str2num(get(app.LotedeDatosYEditField,'Value'));
 
if (get(app.LagrangeButton,'Value') == 0 && get(app.NewtonGregoryProgresivoButton,'Value') == 0 &&  get(app.NewtonGregoryRegresivoButton,'Value') == 0)
    disp('No se seleccionó ningún método');
end
if (length(xi) ~= length(yi))
   msgbox('Los vectores ingresados deben tener la misma cantidad de puntos');

else
    if equis(xi)
        fprintf('Los Puntos son equiespaciados\n')
    else
        fprintf('Los Puntos no son equiespaciados\n')
    end
    switch get(get(app.ElegiralternativaButtonGroup,'SelectedObject'),'Text')
        case 'Lagrange'
           pol = lagrange (xi,yi);
        case 'Newton Gregory Progresivo'
            pol = newton_gregory(xi,yi,1);
        case 'Newton Gregory Regresivo'
            pol = newton_gregory(xi,yi,0);
    end


%simplico la expresion
%pol = lagrange (xi,yi);
%pol = newton_gregory(xi,yi,0);
%pol = vpa(simplify(pol),10);
%disp(pol);
%set(app.Resultados,'String',pol);
%set(app.Resultados,'String',"Hola");
end
%Interpolacion de newton
function pol = newton_gregory(X,Y,prog_o_reg)
    global pn;
    n=length(Y)-1;
    DD=zeros(n+1);
    DD(:,1)=Y;
    for k=2:n+1
        for J=k:n+1
            DD(J,k)=[DD(J,k-1)-DD(J-1,k-1)]/[X(J)-X(J-k+1)];
        end
    end
    disp('La matriz de diferencias divididas es:');
    disp(DD);
    disp('El Polinomio de Newton es:');
    x=sym('x');
    alto=length(DD(:,1));
    polnew=DD(1,1);
    P=1;
    for i=1:n
        P=P*(x-X(i));
        if prog_o_reg == 1
            mult = DD(i+1,i+1);
        end
        if prog_o_reg == 0
            mult = DD(alto,i+1);
        end
        disp(mult);
        polnew=polnew+P*mult;
    end
    polnew=expand(polnew);
    pol = polnew;
    pn=pol;
    pretty(polnew);
end


%metodo que devuelve el polinomio de lagrange segun xi yi
function pol = lagrange(xi,yi)
    global pn;
    n=length(xi);
    x=sym('x'); %esta funcion nos permite dejar la variable 'x' como simbolica
    
    for j=1:n
    producto=1;
        for i=1:j-1
            producto=producto*(x-xi(i)); %calculo del producto 1 superior de L
        end
    
    producto2=1;
        for i=j+1:n
            producto2=producto2*(x-xi(i)); %calculo del producto 2 superior de 
        end
    
    producto3=1;
        for i=1:j-1
            producto3=producto3*(xi(j)-xi(i)); %calculo del producto 3 inferior de L
        end
    
    producto4=1;
    
        for i=j+1:n
            producto4=producto4*(xi(j)-xi(i)); %calculo del producto 4 inferior de L
        end
    
    L(j)=(producto*producto2)/(producto3*producto4); %calculos de las L
    
    fprintf('\n L%d:',j-1);
    disp(L(j));
    
    end
    
    pol=0;
    
    for j=1:n
        pol=pol+L(j)*yi(j); %calculo del polinomio interpolante
    end
    pn=pol;
    pn = vpa(simplify(pn),10);
    disp('El Polinomio de Lagrange es:');
    pol=expand(pol);
    pretty(pol);   
end

        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            if(get(app.IngresarValorKEditField,'Value'))
            global pn;
            x=sym('x');
            x=str2num(get(app.IngresarValorKEditField,'Value'));
            y=eval(pn); %evaluar el punto en el polinomio
            fprintf('La aproximacion a f(%d) es:',x)
            disp(y);
            else
                msgbox("Por favor ingresar un valor K")
            end               
        end

        % Button pushed function: FinalizarButton
        function FinalizarButtonPushed(app, event)
            clc; %Borra el area de trabajo
            clear; %borra las variables almacenadas
            closereq;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FINTERLabel = uilabel(app.UIFigure);
            app.FINTERLabel.Text = 'FINTER';
            app.IngresarDatosPanel = uipanel(app.UIFigure);
            app.IngresarDatosPanel.Title = 'Ingresar Datos';
            app.LotedeDatosYEditFieldLabel = uilabel(app.IngresarDatosPanel);
            app.LotedeDatosYEditFieldLabel.Text = 'Lote de Datos Y:';
            app.LotedeDatosYEditField = uieditfield(app.IngresarDatosPanel, 'text');
            app.LotedeDatosYEditField.Value = '[8,7,5,4]';
            app.LotedeDatosXEditFieldLabel = uilabel(app.IngresarDatosPanel);
            app.LotedeDatosXEditFieldLabel.Text = 'Lote de Datos X:';
            app.LotedeDatosXEditField = uieditfield(app.IngresarDatosPanel, 'text');
            app.LotedeDatosXEditField.Value = '[1,8,7,9]';
            app.InterpolarmediantePolinomiodePanel = uipanel(app.UIFigure);
            app.InterpolarmediantePolinomiodePanel.Title = 'Interpolar mediante Polinomio de:';
            app.InterpolarButton = uibutton(app.InterpolarmediantePolinomiodePanel, 'push');
            app.InterpolarButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolarButtonPushed, true);
            app.InterpolarButton.Text = 'Interpolar';
            app.LosresultadossevernenelCommandWindowLabel = uilabel(app.InterpolarmediantePolinomiodePanel);
            app.LosresultadossevernenelCommandWindowLabel.Text = 'Los resultados se verán en el Command Window ';
            app.ElegiralternativaButtonGroup = uibuttongroup(app.InterpolarmediantePolinomiodePanel);
            app.ElegiralternativaButtonGroup.Title = 'Elegir alternativa';
            app.LagrangeButton = uiradiobutton(app.ElegiralternativaButtonGroup);
            app.LagrangeButton.Text = 'Lagrange';
            app.LagrangeButton.Value = true;
            app.NewtonGregoryProgresivoButton = uiradiobutton(app.ElegiralternativaButtonGroup);
            app.NewtonGregoryProgresivoButton.Text = 'Newton Gregory Progresivo';
            app.NewtonGregoryRegresivoButton = uiradiobutton(app.ElegiralternativaButtonGroup);
            app.NewtonGregoryRegresivoButton.Text = 'Newton Gregory Regresivo';
            app.EspecializarPolinomioenunValorPanel = uipanel(app.UIFigure);
            app.EspecializarPolinomioenunValorPanel.Title = 'Especializar Polinomio en un Valor';
            app.CalcularButton = uibutton(app.EspecializarPolinomioenunValorPanel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.IngresarValorKEditFieldLabel = uilabel(app.EspecializarPolinomioenunValorPanel);
            app.IngresarValorKEditFieldLabel.Text = 'Ingresar Valor K:';
            app.IngresarValorKEditField = uieditfield(app.EspecializarPolinomioenunValorPanel, 'text');
            app.ElresultadoseverenelCommandWindowLabel = uilabel(app.EspecializarPolinomioenunValorPanel);
            app.ElresultadoseverenelCommandWindowLabel.Text = 'El resultado se verá en el Command Window ';
            app.FinalizarButton = uibutton(app.UIFigure, 'push');
            app.FinalizarButton.ButtonPushedFcn = createCallbackFcn(app, @FinalizarButtonPushed, true);
            app.FinalizarButton.Text = 'Finalizar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FINTER

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end